classdef BurialCorrection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParosSurfaceCorrectionCheckup  matlab.ui.Figure
        resettodefaultButton           matlab.ui.control.Button
        CalculateandPlotButton         matlab.ui.control.Button
        BurialDepthmEditField          matlab.ui.control.NumericEditField
        BurialDepthmEditFieldLabel     matlab.ui.control.Label
        ParosDropDown                  matlab.ui.control.DropDown
        ParosDropDownLabel             matlab.ui.control.Label
        HoverDropDown                  matlab.ui.control.DropDown
        HoverDropDownLabel             matlab.ui.control.Label
        PoissonsratiovEditField        matlab.ui.control.NumericEditField
        PoissonsratiovLabel            matlab.ui.control.Label
        degreeofsaturationSEditField   matlab.ui.control.NumericEditField
        degreeofsaturationSEditFieldLabel  matlab.ui.control.Label
        Hydraulicconductivityk_cEditField  matlab.ui.control.NumericEditField
        Hydraulicconductivityk_cLabel  matlab.ui.control.Label
        SedimentPorositynEditField     matlab.ui.control.NumericEditField
        SedimentPorositynLabel         matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        datadir
        localdir
        hovern
        parosn
        droneData
        wiggleData
        startStop % Description
        monthName
        burialDepth
        paros
        parosFill
        lidarMean
        S
        kc
        v
        n
    end


    %     methods (Access = public)
    %
    %         function k = getk(,f,h)
    %
    %             %k = getk(f,h)
    %             %Credit F. Fedderson
    %
    %             % returns the wavenumber of the gravity wave
    %             % dispersion relation, by using newtons method
    %             % the initial guess will be the shallow water wavenumber
    %
    %             omega = 2*pi*f;
    %             g = 9.81;
    %             k = omega./sqrt(g*h);
    %             f = g*k.*tanh(k.*h) - omega.^2;
    %
    %             while max(abs(f))>1e-10
    %                 dfdk = g*k.*h.*(sech(k.*h)).^2 + g*tanh(k.*h);
    %                 k = k - f./dfdk;
    %                 f = g*k.*tanh(k.*h) - omega.^2;
    %             end
    %         end
    %     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.datadir = '/Volumes/FiedlerBot8000/scarp/';
            app.localdir = '~/Documents/Repositories/scarp/';
            app.hovern = 1;
            app.parosn = 2;
            
            %default parameter values
            app.n = 0.3;
            app.kc = 4e-4;
            app.S = 1;
            app.v = 0.3;
            
            app.droneData = [app.datadir 'mat/lidar/drone/20191214_H' num2str(app.hovern) '_navd88_geoid12b_10cm.mat'];
            app.wiggleData = [app.localdir 'mat/20191214_H' num2str(app.hovern) '_wiggle.mat'];
            app.startStop = [app.localdir 'mat/20191214/DroneStartStop_20191214.mat'];
            ptemp = load('../mat/paros.mat');
            app.paros = ptemp.paros;
            clear ptemp
            for i=1:6
                scatter(app.UIAxes2,app.paros(i).crossshore,app.paros(i).z,'ok')
                hold(app.UIAxes2,'on')
            end
            app.parosFill = scatter(app.UIAxes2,app.paros(app.parosn).crossshore,app.paros(app.parosn).z,'or','filled');
            app.lidarMean = text(app.UIAxes2,-50,3,'calculate to add SWL');
        end

        % Value changed function: HoverDropDown
        function HoverDropDownValueChanged(app, event)
            value = app.HoverDropDown.Value;
            app.hovern = str2double(value(5));
            app.monthName = value(1:3);
            if app.monthName == "Dec"
                app.droneData = [app.datadir 'mat/lidar/drone/20191214_H' num2str(app.hovern) '_navd88_geoid12b_10cm.mat'];
                app.wiggleData = [app.localdir 'mat/20191214_H' num2str(app.hovern) '_wiggle.mat'];
                app.startStop = [app.localdir 'mat/20191214/DroneStartStop_20191214.mat'];
            end
            if app.monthName == "Feb"
                app.droneData = [app.datadir 'mat/lidar/drone/20200224_00582_TorreyRunup_H' num2str(app.hovern) '_10cm.mat'];
                app.wiggleData = [app.localdir 'mat/20200224_H' num2str(app.hovern) '_wiggle.mat'];
                app.startStop = [app.localdir 'mat/20200224/DroneStartStop_20200224.mat'];
            end
            
        end

        % Value changed function: BurialDepthmEditField
        function BurialDepthmEditFieldValueChanged(app, event)
            app.burialDepth = app.BurialDepthmEditField.Value;
            
        end

        % Button pushed function: CalculateandPlotButton
        function CalculateandPlotButtonPushed(app, event)
            
            load(app.startStop);
            drone = load(app.droneData,'Processed');
            load(app.wiggleData);
            xind = knnsearch(drone.Processed.x',app.paros(app.parosn).crossshore);
            
            %% get paros data
            % put things into datetime format
            
            tstart = datetime(tstart,'ConvertFrom','datenum');
            tstop = datetime(tstop,'ConvertFrom','datenum');
            
            
            % find which hour to pull from
            tstarthour = dateshift(tstart,'start','hour');
            tind = find(dateshift(app.paros(app.parosn).t+minutes(1),'start','hour') == tstarthour(app.hovern));
            
            % check if hover spans 2 hours
            tstophour = dateshift(tstop,'start','hour');
            if tstophour(app.hovern)>tstarthour(app.hovern)
                tind = [tind tind+1];
            end
            
            % make a time vector for the paros hourly chunk
            for i=1:length(tind)
                tvec(:,i) = app.paros(app.parosn).t(tind(i)):seconds(0.5):app.paros(app.parosn).t(tind(i))+7166*seconds(0.5);
            end
            tvec = tvec(:);
            
            %% interpolate lidar data onto the same time grid
            Hz_lidar = 10;
            Hz_paros = 2;
            tvecHover = tstart(app.hovern):seconds(1/Hz_lidar):tstop(app.hovern);
            
            
            TSdrone = drone.Processed.Zinterp2(:,xind)-wiggle;
            TSdrone = interp1(datetime(drone.Processed.t(~isnan(TSdrone)),'ConvertFrom','datenum'),TSdrone(~isnan(TSdrone)),tvecHover);
            
            % just make sure that the start/stop times for the paros are the same as
            % lidar
            tvecHover2Hz = tstart(app.hovern):seconds(1/Hz_paros):tstop(app.hovern);
            if ~isempty(app.paros(app.parosn).offset)
                pvec = app.paros(app.parosn).eta(:,tind(:))+app.paros(app.parosn).z-app.paros(app.parosn).offset;
            else
                pvec = app.paros(app.parosn).eta(:,tind(:))+app.paros(app.parosn).z;
            end
            TSparos = interp1(tvec,pvec(:),tvecHover2Hz);
            
            % corrected using Raubenheimer
            if ~isempty(app.paros(app.parosn).offset)
                pvec = app.paros(app.parosn).etaCorrected(:,tind(:))+app.paros(app.parosn).z-app.paros(app.parosn).offset;
            else
                pvec = app.paros(app.parosn).etaCorrected(:,tind(:))+app.paros(app.parosn).z;
            end
            TSparosC = interp1(tvec,pvec(:),tvecHover2Hz);


            nfftL = 5*60*Hz_lidar; % 5 minute windows
            nfftP = 5*60*Hz_paros; % 5 minute windows
            
            % get spectra for each instrument (unfiltered)
            [fmp, SppParos, ~, ~, ~, ~] = get_spectrum(TSparos, nfftP, Hz_paros, 0.05);
            [~, SppParosC, ~, ~, ~, ~] = get_spectrum(TSparosC, nfftP, Hz_paros, 0.05);
            
            [fm, SppDrone, ~, ~, ~, ~] = get_spectrum(TSdrone, nfftL, Hz_lidar, 0.05);
            
            load('../mat/sandThickness.mat', 'sandThickness10cmInterp','dtimeHourly');
            burialTime = find(dtimeHourly==tstarthour(app.hovern));
            burial = sandThickness10cmInterp(app.parosn,burialTime);
            if isempty(app.BurialDepthmEditField.Value)
                app.BurialDepthmEditField.Value = burial;
            end
            
            k = getk(fmp,mean(TSparos-app.paros(app.parosn).z));
            
            %% ******prewhiten the timeseries to avoid ringing at beginning/end********
            sizepad = 60*Hz_lidar; %60 second padding - should be enough??
            cutoff = 0.25; %Hz cutoff to match paros cutoff
            
            pad = ones(sizepad,1);
            
            pre = nanmean(TSdrone(1:5*Hz_lidar))*pad;
            post = nanmean(TSdrone(end-5*Hz_lidar:end))*pad;
            ppad = [pre; TSdrone'; post];
            
            D = lowpass(ppad,cutoff,Hz_lidar,'Steepness',0.95); %using matlab's standard here...
            D = D(sizepad+1:end-sizepad); %remove padding
            
            % get spectra for filtered lidar timeseries
            [fm, SppD, ~, ~, ~, ~] = get_spectrum(D, nfftL, Hz_lidar, 0.05);
            
            % do the full pressure correction for burial (Yamamoto 1978)
            G = 2e8; %N/m^2, shear modulus Mei and Foda 1981
            beta = 2.34e-9; %Pa-1, or m2 N-1, from Michallet et al 2019, Table 2
            gamma = 10^4;%kg m-2 s-2, unit weight of the pore fluid
            if isempty(app.burialDepth)
                depth = nanmean(TSparos)-app.paros(app.parosn).z-burial;
            else
                depth = nanmean(TSparos)-app.paros(app.parosn).z-app.burialDepth;
            end
            %do timeseries
            % first, calculate the user parameter tseries
            padlength = 1000;
            pre = fliplr(TSparos(1:padlength));
            post = fliplr(TSparos(end-padlength+1:end));
            ppad = [pre TSparos post];
            nn = length(ppad);
            if mod(nn,2)==0
                nn = nn-1;
                ppad = ppad(1:nn);
            end
            f = Hz_paros/2*linspace(-1,1,nn);
            k = getk(f,depth);
            fY = fft(ppad);
            fY = fftshift(fY);
            [ratio, phaseLag] = burialcorrection(G,app.v,gamma,app.n,app.kc,app.S,beta,ppad,app.BurialDepthmEditField.Value,f,k);
            ratio(abs(f)>cutoff) = 1;
            ratio(isnan(ratio)) = 1;
            phaseLag(isnan(phaseLag)) = 0;
            phaseLag(abs(f)>cutoff) = 0;
            if burial ==0
                ratio = ones(size(ratio));
            end
            correction = cosh(k.*depth);
            correction(abs(f)>cutoff) = 1;
            correction(f==0) = 1;
            fYmag = abs(fY); fYangle = angle(fY);
            fYphaseNew = fYangle+phaseLag;
            fY = fYmag./ratio.*correction.*exp(1i*fYphaseNew);
            fN = ifftshift(fY);
            TSparosPC = abs(ifft(fN));
            TSparosPC = TSparosPC(padlength+1:end-padlength);
            [~, SppParosPC, ~, ~, ~, ~] = get_spectrum(TSparosPC, nfftP, Hz_paros, 0.05);
            
            %plot it up
            hold(app.UIAxes,'off')
            h(1) = loglog(app.UIAxes,fmp,SppParos);
            hold(app.UIAxes,'on')
            h(2) = loglog(app.UIAxes,fm,SppD);
            h(3) = loglog(app.UIAxes,fmp,SppParosC);
            h(4) = loglog(app.UIAxes,fmp,SppParosPC);
            %h(4) = loglog(app.UIAxes,fmp,SppParos./ratio.^2);
            
            hl = legend(app.UIAxes,[h(1) h(2) h(3) h(4)],...
                ['P' num2str(app.parosn)],...
                ['drone -' app.monthName 'H' num2str(app.hovern) '-lowpass filter at ' num2str(cutoff) 'Hz'],...
                ['P' num2str(app.parosn) '- w/ burial correction of ' num2str(burial,'%2.2f') 'm depth'],...
                ['P' num2str(app.parosn) '- w/ user defined inputs']);
            hl.Location = 'southwest';
            hl.AutoUpdate = 'off';
            app.UIAxes.YLim = [1e-8 10];
            app.UIAxes.XLim = [0 1];
            plot(app.UIAxes,cutoff*[1 1],app.UIAxes.YLim,':k')
            plot(app.UIAxes,0.004*[1 1],app.UIAxes.YLim,':k')
            plot(app.UIAxes,0.04*[1 1],app.UIAxes.YLim,':k')
            text(app.UIAxes,0.01,1e-4,'IG','fontsize',16,'FontWeight','bold')
            text(app.UIAxes,0.1,1e-4,'SS','fontsize',16,'FontWeight','bold')
            text(app.UIAxes,0.009,2e-5,['water depth =' num2str(depth,'%2.2f') 'm'],'fontsize',14)
            
            delete(app.lidarMean)
            app.lidarMean = plot(app.UIAxes2,drone.Processed.x,nanmean(drone.Processed.Zinterp2),'b');
            delete(app.parosFill)
            app.parosFill = scatter(app.UIAxes2,app.paros(app.parosn).crossshore,app.paros(app.parosn).z,'or','filled');


            hold(app.UIAxes3,'off')
            h3(1) = plot(app.UIAxes3,tvecHover2Hz,TSparos);
            hold(app.UIAxes3,'on')
            h3(2) = plot(app.UIAxes3,tvecHover,D);
                        

            h3(3) = plot(app.UIAxes3,tvecHover2Hz,TSparosC);
            h3(4) = plot(app.UIAxes3,tvecHover2Hz(1:nn-padlength*2),TSparosPC);
            
            app.UIAxes3.XLim = [tstart(app.hovern)+minutes(1) tstart(app.hovern)+minutes(5)];
            app.UIAxes3.YLim = [min(TSparosPC) max(D)];
            
        end

        % Value changed function: ParosDropDown
        function ParosDropDownValueChanged(app, event)
            value = app.ParosDropDown.Value;
            app.parosn = str2double(value);
        end

        % Value changed function: Hydraulicconductivityk_cEditField
        function Hydraulicconductivityk_cEditFieldValueChanged(app, event)
            value = app.Hydraulicconductivityk_cEditField.Value;
            app.kc = value;
        end

        % Value changed function: degreeofsaturationSEditField
        function degreeofsaturationSEditFieldValueChanged(app, event)
            value = app.degreeofsaturationSEditField.Value;
            app.S = value;
        end

        % Value changed function: PoissonsratiovEditField
        function PoissonsratiovEditFieldValueChanged(app, event)
            value = app.PoissonsratiovEditField.Value;
            app.v = value;
        end

        % Value changed function: SedimentPorositynEditField
        function SedimentPorositynEditFieldValueChanged(app, event)
            value = app.SedimentPorositynEditField.Value;
            app.n = value;
        end

        % Button pushed function: resettodefaultButton
        function resettodefaultButtonPushed(app, event)
            app.hovern = 1;
            app.HoverDropDown.Value = 'Dec:1';
            
            app.parosn = 2;
            app.ParosDropDown.Value = app.parosn;
            
            %default parameter values
            app.n = 0.3;
            app.SedimentPorositynEditField.Value = app.n;
            
            app.kc = 4e-4;
            app.Hydraulicconductivityk_cEditField.Value = app.kc;
            
            app.S = 1;
            app.degreeofsaturationSEditField.Value = app.S;
            
            app.v = 0.3;
            app.PoissonsratiovEditField.Value = app.v;
            
            app.burialDepth = 0.5;
            app.BurialDepthmEditField.Value = app.burialDepth;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParosSurfaceCorrectionCheckup = uifigure('Visible', 'off');
            app.ParosSurfaceCorrectionCheckup.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.ParosSurfaceCorrectionCheckup);
            title(app.UIAxes, 'Sea surface elevation spectra')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Energy (m^2/Hz)')
            app.UIAxes2 = uiaxes(app.ParosSurfaceCorrectionCheckup);
            xlabel(app.UIAxes2, 'cross-shore x (m)')
            ylabel(app.UIAxes2, 'z (m)')
            app.UIAxes3 = uiaxes(app.ParosSurfaceCorrectionCheckup);
            title(app.UIAxes3, 'Time Series')
            xlabel(app.UIAxes3, 'time')
            ylabel(app.UIAxes3, 'z (m)')
            app.SedimentPorositynLabel = uilabel(app.ParosSurfaceCorrectionCheckup);
            app.SedimentPorositynLabel.Text = {'Sediment '; 'Porosity (n)'};
            app.SedimentPorositynEditField = uieditfield(app.ParosSurfaceCorrectionCheckup, 'numeric');
            app.SedimentPorositynEditField.Limits = [0 1];
            app.SedimentPorositynEditField.ValueChangedFcn = createCallbackFcn(app, @SedimentPorositynEditFieldValueChanged, true);
            app.SedimentPorositynEditField.Value = 0.3;
            app.Hydraulicconductivityk_cLabel = uilabel(app.ParosSurfaceCorrectionCheckup);
            app.Hydraulicconductivityk_cLabel.Text = {'Hydraulic '; 'conductivity (k_c)'};
            app.Hydraulicconductivityk_cEditField = uieditfield(app.ParosSurfaceCorrectionCheckup, 'numeric');
            app.Hydraulicconductivityk_cEditField.Limits = [1e-06 0.1];
            app.Hydraulicconductivityk_cEditField.ValueChangedFcn = createCallbackFcn(app, @Hydraulicconductivityk_cEditFieldValueChanged, true);
            app.Hydraulicconductivityk_cEditField.Value = 0.0004;
            app.degreeofsaturationSEditFieldLabel = uilabel(app.ParosSurfaceCorrectionCheckup);
            app.degreeofsaturationSEditFieldLabel.Text = {'degree of '; 'saturation (S)'};
            app.degreeofsaturationSEditField = uieditfield(app.ParosSurfaceCorrectionCheckup, 'numeric');
            app.degreeofsaturationSEditField.Limits = [0 1];
            app.degreeofsaturationSEditField.ValueChangedFcn = createCallbackFcn(app, @degreeofsaturationSEditFieldValueChanged, true);
            app.degreeofsaturationSEditField.Value = 1;
            app.PoissonsratiovLabel = uilabel(app.ParosSurfaceCorrectionCheckup);
            app.PoissonsratiovLabel.Text = {'Poisson''s '; 'ratio (v)'};
            app.PoissonsratiovEditField = uieditfield(app.ParosSurfaceCorrectionCheckup, 'numeric');
            app.PoissonsratiovEditField.Limits = [0 1];
            app.PoissonsratiovEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonsratiovEditFieldValueChanged, true);
            app.PoissonsratiovEditField.Value = 0.3;
            app.HoverDropDownLabel = uilabel(app.ParosSurfaceCorrectionCheckup);
            app.HoverDropDownLabel.Text = 'Hover';
            app.HoverDropDown = uidropdown(app.ParosSurfaceCorrectionCheckup);
            app.HoverDropDown.Items = {'Dec:1', 'Dec:3', 'Dec:4', 'Feb:2', 'Feb:3', 'Feb:4', 'Feb:5'};
            app.HoverDropDown.ValueChangedFcn = createCallbackFcn(app, @HoverDropDownValueChanged, true);
            app.HoverDropDown.Value = 'Dec:1';
            app.ParosDropDownLabel = uilabel(app.ParosSurfaceCorrectionCheckup);
            app.ParosDropDownLabel.Text = 'Paros';
            app.ParosDropDown = uidropdown(app.ParosSurfaceCorrectionCheckup);
            app.ParosDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ParosDropDown.ValueChangedFcn = createCallbackFcn(app, @ParosDropDownValueChanged, true);
            app.ParosDropDown.Value = '2';
            app.BurialDepthmEditFieldLabel = uilabel(app.ParosSurfaceCorrectionCheckup);
            app.BurialDepthmEditFieldLabel.Text = 'Burial Depth (m)';
            app.BurialDepthmEditField = uieditfield(app.ParosSurfaceCorrectionCheckup, 'numeric');
            app.BurialDepthmEditField.Limits = [0 2];
            app.BurialDepthmEditField.ValueChangedFcn = createCallbackFcn(app, @BurialDepthmEditFieldValueChanged, true);
            app.BurialDepthmEditField.Value = 0.5;
            app.CalculateandPlotButton = uibutton(app.ParosSurfaceCorrectionCheckup, 'push');
            app.CalculateandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotButtonPushed, true);
            app.CalculateandPlotButton.Text = 'Calculate and Plot';
            app.resettodefaultButton = uibutton(app.ParosSurfaceCorrectionCheckup, 'push');
            app.resettodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @resettodefaultButtonPushed, true);
            app.resettodefaultButton.Text = 'reset to default';
            % Show the figure after all components are created
            app.ParosSurfaceCorrectionCheckup.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurialCorrection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParosSurfaceCorrectionCheckup)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParosSurfaceCorrectionCheckup)
        end
    end
end